classdef Particlefilter_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        StartpageTab                    matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes5                         matlab.ui.control.UIAxes
        InitializationMapTab            matlab.ui.container.Tab
        ChoosemapLabel                  matlab.ui.control.Label
        LoadMapButton                   matlab.ui.control.Button
        InitializationLabel             matlab.ui.control.Label
        MapEditFieldLabel               matlab.ui.control.Label
        MapEditField                    matlab.ui.control.EditField
        StepsOrientationEditFieldLabel  matlab.ui.control.Label
        StepsOrientationEditField       matlab.ui.control.NumericEditField
        grid_xEditFieldLabel            matlab.ui.control.Label
        grid_xEditField                 matlab.ui.control.NumericEditField
        grid_yEditFieldLabel            matlab.ui.control.Label
        grid_yEditField                 matlab.ui.control.NumericEditField
        NumberofMatlabParallelpoolWorkerEditFieldLabel  matlab.ui.control.Label
        NumberofMatlabParallelpoolWorkerEditField  matlab.ui.control.NumericEditField
        NumberofBinsEditFieldLabel      matlab.ui.control.Label
        NumberofBinsEditField           matlab.ui.control.NumericEditField
        MapResolutionEditFieldLabel     matlab.ui.control.Label
        MapResolutionEditField          matlab.ui.control.NumericEditField
        AngleRangeKinectradEditFieldLabel  matlab.ui.control.Label
        AngleRangeKinectradEditField    matlab.ui.control.NumericEditField
        NumberofIterationEditFieldLabel  matlab.ui.control.Label
        NumberofIterationEditField      matlab.ui.control.NumericEditField
        PointcloudPreparationLabel      matlab.ui.control.Label
        MatlabLabel                     matlab.ui.control.Label
        ParameterTab                    matlab.ui.container.Tab
        ParticlefilterblablablaLabel    matlab.ui.control.Label
        WeightingLabel                  matlab.ui.control.Label
        FunctionButtonGroup             matlab.ui.container.ButtonGroup
        KubicButton                     matlab.ui.control.RadioButton
        LinearButton                    matlab.ui.control.RadioButton
        SigmaEditFieldLabel             matlab.ui.control.Label
        SigmaEditField                  matlab.ui.control.NumericEditField
        MinNumberofParticlesEditFieldLabel  matlab.ui.control.Label
        MinNumberofParticlesEditField   matlab.ui.control.NumericEditField
        ResamplingLabel                 matlab.ui.control.Label
        DispersionXmEditFieldLabel      matlab.ui.control.Label
        DispersionXmEditField           matlab.ui.control.NumericEditField
        DispersionYmEditFieldLabel      matlab.ui.control.Label
        DispersionYmEditField           matlab.ui.control.NumericEditField
        DispersionOrientationradEditFieldLabel  matlab.ui.control.Label
        DispersionOrientationradEditField  matlab.ui.control.NumericEditField
        ReduceNumberParticlesEditFieldLabel  matlab.ui.control.Label
        ReduceNumberParticlesEditField  matlab.ui.control.NumericEditField
        MyEditFieldLabel                matlab.ui.control.Label
        MyEditField                     matlab.ui.control.NumericEditField
        ChooseyourDataLabel             matlab.ui.control.Label
        LoadDataButton                  matlab.ui.control.Button
        DataLabel                       matlab.ui.control.Label
        DataEditField                   matlab.ui.control.EditField
        ProgramButtonGroup              matlab.ui.container.ButtonGroup
        SimulationButton                matlab.ui.control.RadioButton
        LoadMeasurementButton           matlab.ui.control.RadioButton
        TakeMeasurementButton           matlab.ui.control.RadioButton
        RootFolderEditFieldLabel        matlab.ui.control.Label
        RootFolderEditField             matlab.ui.control.EditField
        SimulationTab                   matlab.ui.container.Tab
        XValueEditFieldLabel            matlab.ui.control.Label
        XValueEditField                 matlab.ui.control.NumericEditField
        YValueEditFieldLabel            matlab.ui.control.Label
        YValueEditField                 matlab.ui.control.NumericEditField
        OrientierungradEditFieldLabel   matlab.ui.control.Label
        OrientierungradEditField        matlab.ui.control.NumericEditField
        TestPoseLabel                   matlab.ui.control.Label
        MovementLabel                   matlab.ui.control.Label
        OEditFieldLabel                 matlab.ui.control.Label
        OEditField                      matlab.ui.control.NumericEditField
        SimualtionblablaLabel           matlab.ui.control.Label
        DistanceEditFieldLabel          matlab.ui.control.Label
        DistanceEditField               matlab.ui.control.NumericEditField
        StatsTab                        matlab.ui.container.Tab
        StartParticlefilterButton       matlab.ui.control.Button
        ProgressbarLabel                matlab.ui.control.Label
    end


    properties (Access = public)
        mapResolution % Resolution
        mapPath % Description
    end
    
    properties (Access = private)
        Pax % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Create polar axes and position it in pixels
            app.Pax = polaraxes(app.StartpageTab);
            app.Pax.Units = 'pixels';
            app.Pax.Position = [446   302   473   350];
        end

        % Callback function
        function AddButtonPushed(app, event)
      
        end

        % Size changed function: SimulationTab
        function SimulationTabSizeChanged(app, event)
           
        end

        % Button pushed function: LoadMapButton
        function LoadMapButtonPushed(app, event)
            [file,path] = uigetfile('*.*', 'Select File');
            string = strcat(path,file);            
            app.MapEditField.Value = string;
        end

        % Selection changed function: ProgramButtonGroup
        function ProgramButtonGroupSelectionChanged(app, event)
            selectedButton = app.ProgramButtonGroup.SelectedObject;
            if (app.LoadMeasurementButton.Value == 1)
                
                set (app.LoadDataButton, 'Enable','on');
%                 app.LoadDataButton.Enable = 'on';
            else
                set (app.LoadDataButton, 'Enable','off');
            end
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            [~,path] = uigetfile('*.*', 'Choose your Data');
            [rootPath,b,~] = fileparts(fileparts(path));       
%             disp(rootPath);
            app.RootFolderEditField.Value = rootPath;
            app.DataEditField.Value = b;
        end

        % Button pushed function: StartParticlefilterButton
        function StartParticlefilterButtonPushed(app, event)
            % Simulate / Load / Measure
            % weighting Fctn
            function TestCallBack(iteration)
%                 app.NumberofIterationEditField.Value = iteration;
                app.ProgressbarLabel.Text = [num2str(iteration / (app.NumberofIterationEditField.Value-1) * 100) '%; (Iteration: ' num2str(iteration) ')' ];
                drawnow;
            end
            
            
            if (app.SimulationButton.Value)
                simFlag = 'sim';
            elseif (app.LoadMeasurementButton.Value)
                simFlag = app.DataEditField.Value;
            else 
                simFlag = 'Take';
            end

            UIAxesStats = struct(...
                'LeftAxes' , app.UIAxes3, ...
                'MiddleAxes' , app.UIAxes4, ...
                'RightAxes', app.UIAxes5 ...
            );
        
            UIAxesParticle = struct(...
                'LeftAxes', app.UIAxes, ...
                'RightAxes', app.Pax ...
            );
            
            StartParticlefilter( ...                
                @TestCallBack,   ...
                'AxesStats', UIAxesStats, ...
                'AxesParticle', UIAxesParticle, ...
                'mapPath',          app.MapEditField.Value, ...
                'mapResolution',    app.MapResolutionEditField.Value, ...
                'numWorkers',       app.NumberofMatlabParallelpoolWorkerEditField.Value, ...
                'pose',             [app.XValueEditField.Value, app.YValueEditField.Value, app.OrientierungradEditField.Value], ...
                'movement',         [app.OEditField.Value, app.DistanceEditField.Value], ...
                'sigma',            app.SigmaEditField.Value, ...
                'my',               app.MyEditField.Value, ...
                'minNumParticles',  app.MinNumberofParticlesEditField.Value, ...
                'bins',             app.NumberofBinsEditField.Value, ...
                ... % 'minY', app., ...
                ... % 'maxY', ...
                'dispersion',       [app.DispersionXmEditField.Value, app.DispersionYmEditField.Value, app.DispersionOrientationradEditField.Value], ...
                ... % 'factorParticleReduction', ...
                'mapResolution',    app.MapResolutionEditField.Value, ...
                'mapPath',          app.MapEditField.Value, ...
                'gridX',            app.grid_xEditField.Value, ...
                'gridY',            app.grid_yEditField.Value, ...
                'maxIteration',     app.NumberofIterationEditField.Value, ...
                'angleRange',       app.AngleRangeKinectradEditField.Value, ...
                'simFlag',          simFlag, ...
                'stepsOrientation', app.StepsOrientationEditField.Value, ...
                'dataRootFolder',   app.RootFolderEditField.Value ...
            );
        end

        % Value changed function: MapResolutionEditField
        function MapResolutionEditFieldValueChanged(app, event)
            app.mapResolution = app.MapResolutionEditField.Value;            
        end

        % Value changed function: MapEditField
        function MapEditFieldValueChanged(app, event)
            app.mapPath = app.MapEditField.Value;            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.StartpageTab = uitab(app.TabGroup);
            app.StartpageTab.Title = 'Start page';
            app.UIAxes = uiaxes(app.StartpageTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes3 = uiaxes(app.StartpageTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.UIAxes4 = uiaxes(app.StartpageTab);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            app.UIAxes5 = uiaxes(app.StartpageTab);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            app.InitializationMapTab = uitab(app.TabGroup);
            app.InitializationMapTab.Title = 'Initialization Map';
            app.ChoosemapLabel = uilabel(app.InitializationMapTab);
            app.ChoosemapLabel.Text = 'Choose map:';
            app.LoadMapButton = uibutton(app.InitializationMapTab, 'push');
            app.LoadMapButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMapButtonPushed, true);
            app.LoadMapButton.Text = 'Load Map';
            app.InitializationLabel = uilabel(app.InitializationMapTab);
            app.InitializationLabel.Text = 'Initialization:';
            app.MapEditFieldLabel = uilabel(app.InitializationMapTab);
            app.MapEditFieldLabel.Text = 'Map:';
            app.MapEditField = uieditfield(app.InitializationMapTab, 'text');
            app.MapEditField.ValueChangedFcn = createCallbackFcn(app, @MapEditFieldValueChanged, true);
            app.MapEditField.Editable = 'off';
            app.MapEditField.Value = 'D:\Matlab_Nav_App\2019\Data\Vorgabe_Rundgang.png';
            app.StepsOrientationEditFieldLabel = uilabel(app.InitializationMapTab);
            app.StepsOrientationEditFieldLabel.Text = 'Steps Orientation';
            app.StepsOrientationEditField = uieditfield(app.InitializationMapTab, 'numeric');
            app.StepsOrientationEditField.Limits = [1 Inf];
            app.StepsOrientationEditField.Value = 31;
            app.grid_xEditFieldLabel = uilabel(app.InitializationMapTab);
            app.grid_xEditFieldLabel.Text = 'grid_x';
            app.grid_xEditField = uieditfield(app.InitializationMapTab, 'numeric');
            app.grid_xEditField.Limits = [1 Inf];
            app.grid_xEditField.Value = 1;
            app.grid_yEditFieldLabel = uilabel(app.InitializationMapTab);
            app.grid_yEditFieldLabel.Text = 'grid_y';
            app.grid_yEditField = uieditfield(app.InitializationMapTab, 'numeric');
            app.grid_yEditField.Limits = [1 Inf];
            app.grid_yEditField.Value = 1;
            app.NumberofMatlabParallelpoolWorkerEditFieldLabel = uilabel(app.InitializationMapTab);
            app.NumberofMatlabParallelpoolWorkerEditFieldLabel.Text = 'Number of Matlab Parallelpool Worker:';
            app.NumberofMatlabParallelpoolWorkerEditField = uieditfield(app.InitializationMapTab, 'numeric');
            app.NumberofMatlabParallelpoolWorkerEditField.Limits = [1 Inf];
            app.NumberofMatlabParallelpoolWorkerEditField.Value = 4;
            app.NumberofBinsEditFieldLabel = uilabel(app.InitializationMapTab);
            app.NumberofBinsEditFieldLabel.Text = 'Number of Bins';
            app.NumberofBinsEditField = uieditfield(app.InitializationMapTab, 'numeric');
            app.NumberofBinsEditField.Limits = [1 Inf];
            app.NumberofBinsEditField.Value = 20;
            app.MapResolutionEditFieldLabel = uilabel(app.InitializationMapTab);
            app.MapResolutionEditFieldLabel.Text = 'Map Resolution';
            app.MapResolutionEditField = uieditfield(app.InitializationMapTab, 'numeric');
            app.MapResolutionEditField.Limits = [1 Inf];
            app.MapResolutionEditField.ValueChangedFcn = createCallbackFcn(app, @MapResolutionEditFieldValueChanged, true);
            app.MapResolutionEditField.Value = 20;
            app.AngleRangeKinectradEditFieldLabel = uilabel(app.InitializationMapTab);
            app.AngleRangeKinectradEditFieldLabel.Text = 'Angle Range (Kinect) [rad]';
            app.AngleRangeKinectradEditField = uieditfield(app.InitializationMapTab, 'numeric');
            app.AngleRangeKinectradEditField.Limits = [0 7];
            app.AngleRangeKinectradEditField.Value = 0.96;
            app.NumberofIterationEditFieldLabel = uilabel(app.InitializationMapTab);
            app.NumberofIterationEditFieldLabel.Text = 'Number of Iteration';
            app.NumberofIterationEditField = uieditfield(app.InitializationMapTab, 'numeric');
            app.NumberofIterationEditField.Limits = [1 Inf];
            app.NumberofIterationEditField.Value = 10;
            app.PointcloudPreparationLabel = uilabel(app.InitializationMapTab);
            app.PointcloudPreparationLabel.Text = 'Pointcloud Preparation:';
            app.MatlabLabel = uilabel(app.InitializationMapTab);
            app.MatlabLabel.Text = 'Matlab:';
            app.ParameterTab = uitab(app.TabGroup);
            app.ParameterTab.Title = 'Parameter';
            app.ParticlefilterblablablaLabel = uilabel(app.ParameterTab);
            app.ParticlefilterblablablaLabel.Text = 'Particlefilter blablabla:';
            app.WeightingLabel = uilabel(app.ParameterTab);
            app.WeightingLabel.Text = 'Weighting:';
            app.FunctionButtonGroup = uibuttongroup(app.ParameterTab);
            app.FunctionButtonGroup.Title = 'Function';
            app.KubicButton = uiradiobutton(app.FunctionButtonGroup);
            app.KubicButton.Text = 'Kubic';
            app.KubicButton.Value = true;
            app.LinearButton = uiradiobutton(app.FunctionButtonGroup);
            app.LinearButton.Text = 'Linear';
            app.SigmaEditFieldLabel = uilabel(app.ParameterTab);
            app.SigmaEditFieldLabel.Text = 'Sigma:';
            app.SigmaEditField = uieditfield(app.ParameterTab, 'numeric');
            app.SigmaEditField.Limits = [0 Inf];
            app.SigmaEditField.Value = 0.5;
            app.MinNumberofParticlesEditFieldLabel = uilabel(app.ParameterTab);
            app.MinNumberofParticlesEditFieldLabel.Text = 'Min Number of Particles:';
            app.MinNumberofParticlesEditField = uieditfield(app.ParameterTab, 'numeric');
            app.MinNumberofParticlesEditField.Limits = [1 Inf];
            app.MinNumberofParticlesEditField.Value = 1500;
            app.ResamplingLabel = uilabel(app.ParameterTab);
            app.ResamplingLabel.Text = 'Resampling:';
            app.DispersionXmEditFieldLabel = uilabel(app.ParameterTab);
            app.DispersionXmEditFieldLabel.Text = 'Dispersion X [m]:';
            app.DispersionXmEditField = uieditfield(app.ParameterTab, 'numeric');
            app.DispersionXmEditField.Limits = [0 Inf];
            app.DispersionXmEditField.Value = 0.5;
            app.DispersionYmEditFieldLabel = uilabel(app.ParameterTab);
            app.DispersionYmEditFieldLabel.Text = 'Dispersion Y [m]:';
            app.DispersionYmEditField = uieditfield(app.ParameterTab, 'numeric');
            app.DispersionYmEditField.Limits = [0 Inf];
            app.DispersionYmEditField.Value = 0.5;
            app.DispersionOrientationradEditFieldLabel = uilabel(app.ParameterTab);
            app.DispersionOrientationradEditFieldLabel.Text = 'Dispersion Orientation [rad]:';
            app.DispersionOrientationradEditField = uieditfield(app.ParameterTab, 'numeric');
            app.DispersionOrientationradEditField.Limits = [0 7];
            app.DispersionOrientationradEditField.Value = 0.0491;
            app.ReduceNumberParticlesEditFieldLabel = uilabel(app.ParameterTab);
            app.ReduceNumberParticlesEditFieldLabel.Text = 'Reduce Number Particles';
            app.ReduceNumberParticlesEditField = uieditfield(app.ParameterTab, 'numeric');
            app.ReduceNumberParticlesEditField.Limits = [0.1 1];
            app.ReduceNumberParticlesEditField.Value = 0.95;
            app.MyEditFieldLabel = uilabel(app.ParameterTab);
            app.MyEditFieldLabel.Text = 'My:';
            app.MyEditField = uieditfield(app.ParameterTab, 'numeric');
            app.ChooseyourDataLabel = uilabel(app.ParameterTab);
            app.ChooseyourDataLabel.Text = 'Choose your Data:';
            app.LoadDataButton = uibutton(app.ParameterTab, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Enable = 'off';
            app.LoadDataButton.Text = 'Load Data';
            app.DataLabel = uilabel(app.ParameterTab);
            app.DataLabel.Text = 'Data:';
            app.DataEditField = uieditfield(app.ParameterTab, 'text');
            app.ProgramButtonGroup = uibuttongroup(app.ParameterTab);
            app.ProgramButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ProgramButtonGroupSelectionChanged, true);
            app.ProgramButtonGroup.Title = 'Program';
            app.SimulationButton = uiradiobutton(app.ProgramButtonGroup);
            app.SimulationButton.Text = 'Simulation';
            app.SimulationButton.Value = true;
            app.LoadMeasurementButton = uiradiobutton(app.ProgramButtonGroup);
            app.LoadMeasurementButton.Text = 'Load Measurement';
            app.TakeMeasurementButton = uiradiobutton(app.ProgramButtonGroup);
            app.TakeMeasurementButton.Text = 'Take Measurement';
            app.RootFolderEditFieldLabel = uilabel(app.ParameterTab);
            app.RootFolderEditFieldLabel.Text = 'Root Folder';
            app.RootFolderEditField = uieditfield(app.ParameterTab, 'text');
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.SizeChangedFcn = createCallbackFcn(app, @SimulationTabSizeChanged, true);
            app.SimulationTab.Title = 'Simulation';
            app.XValueEditFieldLabel = uilabel(app.SimulationTab);
            app.XValueEditFieldLabel.Text = 'X-Value:';
            app.XValueEditField = uieditfield(app.SimulationTab, 'numeric');
            app.YValueEditFieldLabel = uilabel(app.SimulationTab);
            app.YValueEditFieldLabel.Text = 'Y-Value:';
            app.YValueEditField = uieditfield(app.SimulationTab, 'numeric');
            app.OrientierungradEditFieldLabel = uilabel(app.SimulationTab);
            app.OrientierungradEditFieldLabel.Text = 'Orientierung [rad]:';
            app.OrientierungradEditField = uieditfield(app.SimulationTab, 'numeric');
            app.TestPoseLabel = uilabel(app.SimulationTab);
            app.TestPoseLabel.Text = 'Test-Pose:';
            app.MovementLabel = uilabel(app.SimulationTab);
            app.MovementLabel.Text = 'Movement:';
            app.OEditFieldLabel = uilabel(app.SimulationTab);
            app.OEditFieldLabel.Text = 'O'':';
            app.OEditField = uieditfield(app.SimulationTab, 'numeric');
            app.OEditField.Limits = [0 Inf];
            app.SimualtionblablaLabel = uilabel(app.SimulationTab);
            app.SimualtionblablaLabel.Text = 'Simualtion blabla:';
            app.DistanceEditFieldLabel = uilabel(app.SimulationTab);
            app.DistanceEditFieldLabel.Text = 'Distance:';
            app.DistanceEditField = uieditfield(app.SimulationTab, 'numeric');
            app.DistanceEditField.Limits = [0 Inf];
            app.StatsTab = uitab(app.TabGroup);
            app.StatsTab.Title = 'Stats';
            app.StartParticlefilterButton = uibutton(app.UIFigure, 'push');
            app.StartParticlefilterButton.ButtonPushedFcn = createCallbackFcn(app, @StartParticlefilterButtonPushed, true);
            app.StartParticlefilterButton.Text = 'Start Particlefilter';
            app.ProgressbarLabel = uilabel(app.UIFigure);
            app.ProgressbarLabel.Text = 'Progressbar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Particlefilter_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end